classdef Editor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Toolbar              matlab.ui.container.Toolbar
        PushTool             matlab.ui.container.toolbar.PushTool
        GridLayout           matlab.ui.container.GridLayout
        ResolveMeshButton    matlab.ui.control.Button
        AlphaEditFieldLabel  matlab.ui.control.Label
        AlphaEditField       matlab.ui.control.NumericEditField
        ImportButton         matlab.ui.control.Button
        Slider               matlab.ui.control.Slider
        SliderLabel          matlab.ui.control.Label
        AddButton_2          matlab.ui.control.Button
        LayerSpinner         matlab.ui.control.Spinner
        LayerSpinnerLabel    matlab.ui.control.Label
        ShapesListBox        matlab.ui.control.ListBox
        EditShapeButton      matlab.ui.control.Button
        MeshButton           matlab.ui.control.Button
        ExportButton         matlab.ui.control.Button
        AddButton            matlab.ui.control.Button
        ObjectsListBox       matlab.ui.control.ListBox
        Area_ROI             matlab.ui.control.UIAxes
        Area_Img             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        objs % Description
        current % Description
        alpha_parm % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.objs = {};
            app.current = 0;
            app.alpha_parm = inf;
            pbaspect(app.Area_Img,[1 1 1]);
            pbaspect(app.Area_ROI,[1 1 1]);
        end

        % Button pushed function: AddButton
        function addObj(app, event)
            [file,path] = uigetfile("*.png");
            
            if file ~= 0
                app.current = length(app.objs)+1;
                [img,~,mask]=imread(append(path,file));
                app.objs{app.current} = {file,img,mask,{}};
                %app.ObjectsListBox.Items{1+length(app.ObjectsListBox.Items)} = {str(file)};
            
                image(app.Area_Img,app.objs{app.current}{2});
                    
                [W,H,~]=size(img);
                
                app.Area_Img.XLim = [-inf,inf];
                app.Area_Img.YLim = [-inf,inf];
                
                app.Area_ROI.YDir = 'reverse';    
                app.Area_ROI.XLim = [1,W];
                app.Area_ROI.YLim = [1,H];
            end
        end

        % Button pushed function: ResolveMeshButton
        function ResolveMesh(app, event)
            ROI = app.objs{app.current}{4};
            points_num = length(ROI);
            points = zeros(points_num,2);
            for i = 1:points_num
                points(i,1) = ROI{i}.Position(1);
                points(i,2) = ROI{i}.Position(2);
            end
            app.objs{app.current}{5} = points;
            mesh = point2mesh(points,app.alpha_parm);
            app.objs{app.current}{6} = mesh;
            [mesh_num,~] = size(mesh);
            
            image(app.Area_Img,app.objs{app.current}{2});
            hold(app.Area_Img,"on");
            for i = 1:mesh_num
                idxs = mesh(i,:);
                plot(app.Area_Img,[points(idxs,1); points(idxs(1),1)],[points(idxs,2); points(idxs(1),2)])
            end
            hold(app.Area_Img,"off");
            
            %app.objs{app.current}{5} = {};
            %app.objs{app.current}{6} = {};
        end

        % Value changed function: AlphaEditField
        function AlphaEditFieldValueChanged(app, event)
            app.alpha_parm = app.AlphaEditField.Value;
        end

        % Callback function
        function AddPoint(app, event)
            if app.current ~= 0
                app.objs{app.current}{4}{1+length(app.objs{app.current}{4})} = drawpoint(app.Area_ROI);   
            end    
        end

        % Button down function: Area_ROI
        function Area_ROIButtonDown2(app, event)
            if app.current ~= 0
                app.objs{app.current}{4}{1+length(app.objs{app.current}{4})} = drawpoint(app.Area_ROI);   
            end    
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            [file,path] = uiputfile('*.mat');
            if file ~= 0
                objs = app.objs;
                save(append(path,file),"objs",'-mat');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Area_Img = uiaxes(app.GridLayout);
            xlabel(app.Area_Img, 'X')
            ylabel(app.Area_Img, 'Y')
            zlabel(app.Area_Img, 'Z')
            app.Area_ROI = uiaxes(app.GridLayout);
            xlabel(app.Area_ROI, 'X')
            ylabel(app.Area_ROI, 'Y')
            zlabel(app.Area_ROI, 'Z')
            app.Area_ROI.ButtonDownFcn = createCallbackFcn(app, @Area_ROIButtonDown2, true);
            app.ObjectsListBox = uilistbox(app.GridLayout);
            app.ObjectsListBox.Items = {};
            app.ObjectsListBox.Value = {};
            app.AddButton = uibutton(app.GridLayout, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @addObj, true);
            app.AddButton.Text = 'Add';
            app.ExportButton = uibutton(app.GridLayout, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.MeshButton = uibutton(app.GridLayout, 'push');
            app.MeshButton.Text = 'Mesh';
            app.EditShapeButton = uibutton(app.GridLayout, 'push');
            app.EditShapeButton.Text = 'Edit Shape';
            app.ShapesListBox = uilistbox(app.GridLayout);
            app.ShapesListBox.Items = {};
            app.ShapesListBox.Value = {};
            app.LayerSpinnerLabel = uilabel(app.GridLayout);
            app.LayerSpinnerLabel.Text = 'Layer';
            app.LayerSpinner = uispinner(app.GridLayout);
            app.AddButton_2 = uibutton(app.GridLayout, 'push');
            app.AddButton_2.Text = 'Add';
            app.SliderLabel = uilabel(app.GridLayout);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.GridLayout);
            app.ImportButton = uibutton(app.GridLayout, 'push');
            app.ImportButton.Text = 'Import';
            app.AlphaEditField = uieditfield(app.GridLayout, 'numeric');
            app.AlphaEditField.ValueChangedFcn = createCallbackFcn(app, @AlphaEditFieldValueChanged, true);
            app.AlphaEditField.Value = Inf;
            app.AlphaEditFieldLabel = uilabel(app.GridLayout);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.ResolveMeshButton = uibutton(app.GridLayout, 'push');
            app.ResolveMeshButton.ButtonPushedFcn = createCallbackFcn(app, @ResolveMesh, true);
            app.ResolveMeshButton.Text = 'Resolve Mesh';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Editor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end